classdef GUItest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button                   matlab.ui.control.StateButton
        PourMix2Button           matlab.ui.control.Button
        PourMix1Button           matlab.ui.control.Button
        PourCognacButton         matlab.ui.control.Button
        ControlRobotWithControllerLabel  matlab.ui.control.Label
        ManualControlButton      matlab.ui.control.Button
        StartSimulationLabel     matlab.ui.control.Label
        ChooseADrinkToMakeLabel  matlab.ui.control.Label
        BarSimulationLabel       matlab.ui.control.Label
        EmergencyStopButton      matlab.ui.control.Button
        ResumeButton             matlab.ui.control.Button
        LoadEnvironmentButton    matlab.ui.control.Button
        PourWhiskyButton         matlab.ui.control.Button
        PourVodkaButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        % Creat timer that constantly checks ESTOP
        estopTimer = timer('ExecutionMode', 'fixedRate', ...
                           'Period', 0.1, ... % Check every 0.1 seconds
                           'TimerFcn', @(~,~) checkEmergencyStop(app));
        buttonPressed = false;
        a;
        state;

% CODE FROM ASSIGNMENT2()

        robot1 = [];% Robot 1 model
        gripperBase1 = []; % Robot 1's gripper base model
        gripperLeft1 = cell(1,2); % stores (gripper claw 1, angle)
        gripperRight1 = cell(1,2); % stores (gripper claw 2, angle)

        robot2 = [];% Robot 2 model
        gripperBase2 = []; % Robot 2's gripper base model
        gripperLeft2 = cell(1,2); % stores (gripper claw 1, angle)
        gripperRight2 = cell(1,2); % stores (gripper claw 2, angle)

        bottles = cell(1,9); % Array to store bottle objects
        bottlePositions = []; % Array to store bottle positions
        holdbottle_ = [false 1;
                       false 2]; % stores whether the gripper is holding a bottle, and the index of the bottle (from array 'bottles')
        
        steps = 5; %should be an even number
        dt = 0.01;  % Discrete time step for simulation
        
        suppressGraphics = false; % turns off most graphic objects when true (speeds up sim heaps)

        safeBound;

        % Shelf positions (x1 y1 z1; x2 y2 z2; etc)
        shelf_pos_arr = [
                         0.6 2.15 1.48; % whisky         - 1
                         0.6 2.15 1.1; % vodka           - 2
                         0.8 2.15 1.48; % cognac         - 3
                         0 0 0;
                         0 0 0;
                         0.8 2.15 1.1; % vermouth        - 4
                         1.0 2.15 1.48; % gin            - 5
                         1.0 2.15 1.1;] % moonshine      - 6
                         

        mixer_pos = [0,0.5,0.72];
        glass_spawn = [-0.4,0.5,0.72];
        glass_end_loc = [0,-3,0.2];

        joy = [];
        joy_status = false;
        joyToggle = false;

        isEmergencyStopTriggered = false;

    end
    
    
    methods (Access = public)

        function checkEmergencyStop(app)
        % function to check if the estop is pressed

        % Read the state of the emergency stop button from Arduino
        app.state = readDigitalPin(app.a, 'D2');
            
            % If either GUI or Arduino ESTOP button is pressed
            if (app.EmergencyStopButton.UserData) || (app.state && ~app.buttonPressed)
                % Update logic statements
                app.self.isEmergencyStopTriggered = true; % not sure why doesn't work
                app.buttonPressed = true;
                disp('ESTOP PRESSED1');
                % Trigger the Emergency Stop callback
                EmergencyStopButtonPushed(app);
        
            end
        end

% CODE FROM ASSIGNMENT2()
        %% Initialize Scene
        function app = initialiseScene(app) % Load and place objects in the scene
               
            % Reset log file
            Logger().write('\erase');

            % attempt to connect to controller
            try
                app.joy = vrjoystick(1);
                app.joy_status = true;
            catch ERR
                disp(ERR.message);
            end
            
            %Plot the robot in it's initial configuration
            clf;
            app.robot1 = UR30(eye(4) * transl(0,1.45,1) * trotx(0) * troty(0));
            hold on;
            app.robot2 = UR30(eye(4) * transl(0,-0.5,1) * trotx(0) * troty(0));

         
            xlim([-5, 5]);
            ylim([-5, 5]);
            zlim([-5, 5]);
            axis tight;
            app.robot1.model.animate(deg2rad([-230 -60 100 -40 -50 0])); % Robot's initial joint configuration
            app.gripperBase1 = PlaceObject('gripperBase.ply',[0,0,0]);
            app.gripperLeft1{1} = PlaceObject('gripperClaw.ply',[0,0,0]);
            app.gripperLeft1{2} = deg2rad(20);
            app.gripperRight1{1} = PlaceObject('gripperClaw.ply',[0,0,0]);
            app.gripperRight1{2} = deg2rad(20);

            app.robot2.model.animate(deg2rad([-10 -60 100 -137 -5 90])); % Robot's initial joint configuration
            app.gripperBase2 = PlaceObject('gripperBase.ply',[0,0,0]);
            app.gripperLeft2{1} = PlaceObject('gripperClaw.ply',[0,0,0]);
            app.gripperLeft2{2} = deg2rad(20);
            app.gripperRight2{1} = PlaceObject('gripperClaw.ply',[0,0,0]);
            app.gripperRight2{2} = deg2rad(20);

            app = app.commandGrippers('update');
            
            % Plot vital objects around the scene
            t = hgtransform;
            app.bottles{1} = PlaceObject('Whisky.ply',[0,0,0]);
            set(app.bottles{1}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(1,:))));
            
            t = hgtransform;
            app.bottles{2} = PlaceObject('Vodka.ply',[0,0,0]);
            set(app.bottles{2}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(2,:))));
            
            t = hgtransform;
            app.bottles{3} = PlaceObject('Cognac.ply',[0,0,0]);
            set(app.bottles{3}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(3,:))));

            t = hgtransform;
            app.bottles{4} = PlaceObject('Glass.ply',[0,0,0]);
            set(app.bottles{4}, 'Parent', t);
            set(t, 'Matrix', (transl(app.glass_spawn)));
            
            t = hgtransform;
            app.bottles{5} = PlaceObject('Shaker_cup_base.ply',[0,0,0]);
            set(app.bottles{5}, 'Parent', t);
            set(t, 'Matrix', (transl(app.mixer_pos)));

            t = hgtransform;
            app.bottles{6} = PlaceObject('Vermouth.ply',[0,0,0]);
            set(app.bottles{6}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(6,:))));

            t = hgtransform;
            app.bottles{7} = PlaceObject('Gin.ply',[0,0,0]);
            set(app.bottles{7}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(7,:))));

            t = hgtransform;
            app.bottles{8} = PlaceObject('Moonshine.ply',[0,0,0]);
            set(app.bottles{8}, 'Parent', t);
            set(t, 'Matrix', (transl(app.shelf_pos_arr(8,:))));

            if ~app.suppressGraphics
                % Plot extra objects around the envionment

                % Add wood to floor
                surf([-4,-4;4,4] ...        % x axis
                ,[-4,3;-4,3] ...            % y axis
                ,[0,0;0,0] ...          % z axis
                ,'CData',imread('floor.jpg') ...
                ,'FaceColor','texturemap');
              
                
                 % Left side wall
                surf([-4,-4;-4,-4], ...  % x axis
                [-4,-4;3,3], ...     % y axis
                 [0,2.8;0,2.8], ...        % z axis
              'CData', imread('Wall.jpeg'), ... % uploading image to surface
              'FaceColor', 'texturemap');

                % Right side wall
                surf([-4,-4;4,4], ...  % x axis
                [3,3;3,3], ...     % y axis
                 [0,2.8;0,2.8], ...        % z axis
              'CData', imread('Wall.jpeg'), ... % uploading image to surface
              'FaceColor', 'texturemap');

                % Door
                surf([-3.99,-3.99;-3.99,-3.99], ...  % x axis
                [-2,-2;-1,-1], ...     % y axis
                 [0,2;0,2], ...        % z axis
              'CData', imread('Door.jpg'), ... % uploading image to surface
              'FaceColor', 'texturemap');

                % TAB sign
                surf([-3.99,-3.99;-3.99,-3.99], ...  % x axis
                [-1.8,-1.8;-1.2,-1.2], ...     % y axis
                 [2.2,2.8;2.2,2.8], ...        % z axis
              'CData', imread('TAB.jpeg'), ... % uploading image to surface
              'FaceColor', 'texturemap');

                % Bar logo
                surf([-3.99,-3.99;-3.99,-3.99], ...  % x axis
                [0,0;2,2], ...     % y axis
                 [0.5,2.5;0.5,2.5], ...        % z axis
              'CData', imread('Cyber Lounge logo.jpeg'), ... % uploading image to surface
              'FaceColor', 'texturemap');

                % Warning signs
                surf([-2,-2;-2,-2], ...  % x axis
                [2,2;3,3], ...     % y axis
                 [2.2,2.8;2.2,2.8], ...        % z axis
              'CData', imread('Warning.jpeg'), ... % uploading image to surface
              'FaceColor', 'texturemap');

                 surf([2,2;2,2], ...  % x axis
                [2,2;3,3], ...     % y axis
                 [2.2,2.8;2.2,2.8], ...        % z axis
              'CData', imread('Warning.jpeg'), ... % uploading image to surface
              'FaceColor', 'texturemap');
    
                % Add fire esintguisher sign to wall
                surf([2,3;2,3] ...               % x axis
                ,[2.99,2.99;2.99,2.99] ...                           % y axis
                ,[2.5,2.5;1.25,1.25] ...                 % z axis
                ,'CData',imread('FireExtinguisherSign.png') ...
                ,'FaceColor','texturemap');
    
                % Place the bar at specified location
                PlaceObject('Bar.PLY',[0,0,0]);

                % Place the fire extinguisher at specified location
                PlaceObject('fireExtinguisherElevated.ply',[2.5,2.25,0.465]);
    
                % Place the estop at specified location
                PlaceObject('emergencyStopButton.ply',[1.7,-1.7,1.1]);
    
                % Place the estop at specified location
                PlaceObject('emergencyStopButton.ply',[-1.7,-1.7,1.1]);
    
                % Robot pole 1
                t = hgtransform;
                robot1Pole = PlaceObject('robot1Pole.ply',[0,0,0]);
                set(robot1Pole, 'Parent', t);
                set(t, 'Matrix', transl(0,1.57,-0.02));

                % Robot pole 2
                t = hgtransform;
                robot2Pole = PlaceObject('robot1Pole.ply',[0,0,0]);
                set(robot2Pole, 'Parent', t);
                set(t, 'Matrix', transl(0,-0.38,-0.02));

                % Work bench
                t = hgtransform;
                midTable = PlaceObject('midTable.ply',[0,0,0]);
                set(midTable, 'Parent', t);
                set(t, 'Matrix', transl(0,0.5,0.2));

                % Person 1
                t = hgtransform;
                person1 = PlaceObject('Man1.ply',[0,0,0]);
                set(person1, 'Parent', t);
                set(t, 'Matrix', transl(-3,-3,0));

                % Person 2
                t = hgtransform;
                person2 = PlaceObject('Man2.ply',[0,0,0]);
                set(person2, 'Parent', t);
                set(t, 'Matrix', transl(0,-3,0));

                % Code for transparent barrier
                % dimensions for each cuboid
                dimensions = [
                    0.01, 3.3, 2;    % Dimensions for the first cuboid (width, depth, height)
                    1, 0.01, 2;    % Dimensions for the second cuboid
                    1, 0.01, 2;    % Dimensions for the third cuboid
                    0.01, 3.3, 2;    % Dimensions for the fourth cuboid
                ];
                
                % centers for each cuboid section
                centers = [
                    1.3,  0.3,  1;    % Center for the first cuboid
                    0.8, -1.35,  1;     % Center for the second cuboid
                    -0.85,  -1.35,  1;     % Center for the third cuboid
                    -1.35,  0.3,  1;     % Center for the fourth cuboid
                ];
                
                for k = 1:size(centers, 1)
                    % Get the center and dimensions for this cuboid
                    center_x = centers(k, 1);
                    center_y = centers(k, 2);
                    center_z = centers(k, 3);
                    width = dimensions(k, 1);
                    depth = dimensions(k, 2);
                    height = dimensions(k, 3);
                
                % Define the corner coordinates of the cuboid
                x = [-width/2, width/2, width/2, -width/2, -width/2, width/2, width/2, -width/2] + center_x;
                y = [-depth/2, -depth/2, depth/2, depth/2, -depth/2, -depth/2, depth/2, depth/2] + center_y;
                z = [0, 0, 0, 0, height, height, height, height] + center_z - height/2;
                
                % Define each face as a separate patch to allow transparency
                faces = {
                    [1, 2, 3, 4], ...% Bottom face
                    [5, 6, 7, 8], ...% Top face
                    [1, 2, 6, 5], ...% Front face
                    [2, 3, 7, 6], ...% Right face
                    [3, 4, 8, 7], ...% Back face
                    [4, 1, 5, 8]  ...% Left face
                };
                
                % Create each face with transparency
                    for i = 1:length(faces)
                        app.safeBound(k, i) = patch(x(faces{i}), y(faces{i}), z(faces{i}), 'w', ...
                            'FaceAlpha', 0.2, 'EdgeColor', 'white'); % Green color with transparency
                    end
                end
            end

            drawnow();
            Logger().write('Initialisation finished');
        end
        

        %% Gui command to mix drink variant 1
        function app = mixerOption1(app)
            % Part 1 is grabbing the ingredients and all that

            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1), app.shelf_pos_arr(1,2)-0.3, app.shelf_pos_arr(1,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 1 1 1], 1);
            app.holdbottle_(1,:) = [true 1];
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.1,-0.07,0.17) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(pi/2), 1);

            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(-pi/2), 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * troty(pi/2), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1)+0.5, app.shelf_pos_arr(1,2)-0.5, app.shelf_pos_arr(1,3)-0.2) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1), app.shelf_pos_arr(1,2)-0.3, app.shelf_pos_arr(1,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 0 0 0], 1);
            app.holdbottle_(1,:) = [false 1];
            app.SnapBottleTo(1, app.shelf_pos_arr(1,:), [0,0,0]);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 1 1 1], 1);
            

            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(7,1), app.shelf_pos_arr(7,2)-0.3, app.shelf_pos_arr(7,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 1 1 1], 1);
            app.holdbottle_(1,:) = [true 7];
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.1,-0.07,0.17) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(pi/2), 1);

            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(-pi/2), 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * troty(pi/2), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(7,1)+0.5, app.shelf_pos_arr(7,2)-0.5, app.shelf_pos_arr(7,3)-0.2) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(7,1), app.shelf_pos_arr(7,2)-0.3, app.shelf_pos_arr(7,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 0 0 0], 1);
            app.holdbottle_(1,:) = [false 7];
            app.SnapBottleTo(7, app.shelf_pos_arr(7,:), [0,0,0]);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 1 1 1], 1);
            
            
            %part 2:
            % robot 2
            % Move to shaker
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Hold shaker
            app.holdbottle_(2,:) = [true 5];
            % % Perform shaking (no rotations - for certain drinks)
            % app = app.moveToPoseRMRC(transl(-1,-1,1.5),[1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-0.5,-1,1.5), [1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-1,-1,1.5),[1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-0.5,-1,1.5), [1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-1,-1,1.5),[1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-0.5,-1,1.5), [1 1 1 0 0 0], 2 );

            % Performing shaking (with rotations - for other drinks)
            app = app.moveToPoseRMRC(transl(-1,-1,1.5) * trotz(pi/2),[1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-0.5,-1,1.5) * trotz(-pi/2), [1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-1,-1,1.5) * trotz(pi/2),[1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-0.5,-1,1.5) * trotz(-pi/2), [1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-1,-1,1.5) * trotz(pi/2),[1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-0.5,-1,1.5) * trotz(-pi/2), [1 1 1 0 0 0], 2 );

            % Move to above glass
            app = app.moveToPoseIkcon(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)+0.2) * trotx(-pi/2), 2 );
            % Move to the shaker's start position
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % let go of shaker 
            app.holdbottle_(2,:) = [false 5];
            % move to glass start pos
            app = app.moveToPoseRMRC(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)), [1 1 1 0 0 0], 2 );
            % Pick up glass
            app.holdbottle_(2,:) = [true 4];   % where x is the index of the bottle in app.bottles
            % Move to drop off location (for user pickup)
            app = app.moveToPoseRMRC(transl(app.glass_end_loc(1,1),app.glass_end_loc(1,2),app.glass_end_loc(1,3)), [1 1 1 0 0 0], 2 );
            % let go of bottle
            app.holdbottle_(2,:) = [false 4];   % where x is the index of the bottle in app.bottles
            % Move back to shaker (start spot)
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Move glass and shaker back 
            app.SnapBottleTo(4, app.glass_spawn,[0,0,0]);
            app.SnapBottleTo(5, app.mixer_pos,[0,0,0]);


        end

        %% Gui command to mix drink variant 2
        function app = mixerOption2(app)
            
            % Part 1 is grabbing the ingredients and all that

            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1), app.shelf_pos_arr(3,2)-0.3, app.shelf_pos_arr(3,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 1 1 1], 1);
            app.holdbottle_(1,:) = [true 3];
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.1,-0.07,0.17) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(pi/2), 1);

            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(-pi/2), 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * troty(pi/2), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1)+0.5, app.shelf_pos_arr(3,2)-0.5, app.shelf_pos_arr(3,3)-0.2) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1), app.shelf_pos_arr(3,2)-0.3, app.shelf_pos_arr(3,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 0 0 0], 1);
            app.holdbottle_(1,:) = [false 3];
            app.SnapBottleTo(3, app.shelf_pos_arr(3,:), [0,0,0]);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 1 1 1], 1);
            

            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(8,1), app.shelf_pos_arr(8,2)-0.3, app.shelf_pos_arr(8,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 1 1 1], 1);
            app.holdbottle_(1,:) = [true 8];
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.1,-0.07,0.17) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(pi/2), 1);

            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(-pi/2), 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * troty(pi/2), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(8,1)+0.5, app.shelf_pos_arr(8,2)-0.5, app.shelf_pos_arr(8,3)-0.2) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(8,1), app.shelf_pos_arr(8,2)-0.3, app.shelf_pos_arr(8,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 0 0 0], 1);
            app.holdbottle_(1,:) = [false 8];
            app.SnapBottleTo(8, app.shelf_pos_arr(8,:), [0,0,0]);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 1 1 1], 1);
            
            
            %part 2:
            % robot 2
            % Move to shaker
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Hold shaker
            app.holdbottle_(2,:) = [true 5];
            % % Perform shaking (no rotations - for certain drinks)
            % app = app.moveToPoseRMRC(transl(-1,-1,1.5),[1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-0.5,-1,1.5), [1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-1,-1,1.5),[1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-0.5,-1,1.5), [1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-1,-1,1.5),[1 1 1 0 0 0], 2 );
            % app = app.moveToPoseRMRC(transl(-0.5,-1,1.5), [1 1 1 0 0 0], 2 );

            % Performing shaking (with rotations - for other drinks)
            app = app.moveToPoseRMRC(transl(-1,-1,1.5) * trotz(pi/2),[1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-0.5,-1,1.5) * trotz(-pi/2), [1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-1,-1,1.5) * trotz(pi/2),[1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-0.5,-1,1.5) * trotz(-pi/2), [1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-1,-1,1.5) * trotz(pi/2),[1 1 1 0 0 0], 2 );
            app = app.moveToPoseRMRC(transl(-0.5,-1,1.5) * trotz(-pi/2), [1 1 1 0 0 0], 2 );

            % Move to above glass
            app = app.moveToPoseRMRC(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)+0.2), [1 1 1 0 0 0], 2 );
            % Move to the shaker's start position
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % let go of shaker 
            app.holdbottle_(2,:) = [false 5];
            % move to glass start pos
            app = app.moveToPoseRMRC(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)), [1 1 1 0 0 0], 2 );
            % Pick up glass
            app.holdbottle_(2,:) = [true 4];   % where x is the index of the bottle in app.bottles
            % Move to drop off location (for user pickup)
            app = app.moveToPoseRMRC(transl(app.glass_end_loc(1,1),app.glass_end_loc(1,2),app.glass_end_loc(1,3)), [1 1 1 0 0 0], 2 );
            % let go of bottle
            app.holdbottle_(2,:) = [false 4];   % where x is the index of the bottle in app.bottles
            % Move back to shaker (start spot)
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Move glass and shaker back 
            app.SnapBottleTo(4, app.glass_spawn,[0,0,0]);
            app.SnapBottleTo(5, app.mixer_pos,[0,0,0]);

        end

        %% Gui command to pour straight drink variant 1
        function app = straightDrink1(app)
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1), app.shelf_pos_arr(1,2)-0.3, app.shelf_pos_arr(1,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 1 1 1], 1);
            app.holdbottle_(1,:) = [true 1];
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.1,-0.07,0.17) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(pi/2), 1);

            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(-pi/2), 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * troty(pi/2), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1)+0.5, app.shelf_pos_arr(1,2)-0.5, app.shelf_pos_arr(1,3)-0.2) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(1,1), app.shelf_pos_arr(1,2)-0.3, app.shelf_pos_arr(1,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 0 0 0], 1);
            app.holdbottle_(1,:) = [false 1];
            app.SnapBottleTo(1, app.shelf_pos_arr(1,:), [0,0,0]);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 1 1 1], 1);

            % robot 2
            % Move to shaker
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Hold shaker
            app.holdbottle_(2,:) = [true 5];

            % Move to above glass
            app = app.moveToPoseRMRC(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)+0.2), [1 1 1 0 0 0], 2 );
            % Move to the shaker's start position
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % let go of shaker 
            app.holdbottle_(2,:) = [false 5];
            % move to glass start pos
            app = app.moveToPoseRMRC(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)), [1 1 1 0 0 0], 2 );
            % Pick up glass
            app.holdbottle_(2,:) = [true 4];   % where x is the index of the bottle in app.bottles
            % Move to drop off location (for user pickup)
            app = app.moveToPoseRMRC(transl(app.glass_end_loc(1,1),app.glass_end_loc(1,2),app.glass_end_loc(1,3)), [1 1 1 0 0 0], 2 );
            % let go of bottle
            app.holdbottle_(2,:) = [false 4];   % where x is the index of the bottle in app.bottles
            % Move back to shaker (start spot)
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Move glass and shaker back 
            app.SnapBottleTo(4, app.glass_spawn,[0,0,0]);
            app.SnapBottleTo(5, app.mixer_pos,[0,0,0]);
            
            
        end

        %% Gui command to pour straight drink variant 2
        function app = straightDrink2(app)
            
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(2,1), app.shelf_pos_arr(2,2)-0.3, app.shelf_pos_arr(2,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 1 1 1], 1);
            app.holdbottle_(1,:) = [true 2];
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.12,-0.07,0.17) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(pi/2), 1);

            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(-pi/2), 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * troty(pi/2), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(2,1)+0.5, app.shelf_pos_arr(2,2)-0.5, app.shelf_pos_arr(2,3)-0.2) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(2,1), app.shelf_pos_arr(2,2)-0.3, app.shelf_pos_arr(2,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 0 0 0], 1);
            app.holdbottle_(1,:) = [false 2];
            app.SnapBottleTo(2, app.shelf_pos_arr(2,:), [0,0,0]);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 1 1 1], 1);

             % robot 2
            % Move to shaker
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Hold shaker
            app.holdbottle_(2,:) = [true 5];
            % Move to above glass
            app = app.moveToPoseRMRC(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)+0.2), [1 1 1 0 0 0], 2 );
            % Move to the shaker's start position
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % let go of shaker 
            app.holdbottle_(2,:) = [false 5];
            % move to glass start pos
            app = app.moveToPoseRMRC(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)), [1 1 1 0 0 0], 2 );
            % Pick up glass
            app.holdbottle_(2,:) = [true 4];   % where x is the index of the bottle in app.bottles
            % Move to drop off location (for user pickup)
            app = app.moveToPoseRMRC(transl(app.glass_end_loc(1,1),app.glass_end_loc(1,2),app.glass_end_loc(1,3)), [1 1 1 0 0 0], 2 );
            % let go of bottle
            app.holdbottle_(2,:) = [false 4];   % where x is the index of the bottle in app.bottles
            % Move back to shaker (start spot)
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Move glass and shaker back 
            app.SnapBottleTo(4, app.glass_spawn,[0,0,0]);
            app.SnapBottleTo(5, app.mixer_pos,[0,0,0]);
        end

        %% Gui command to pour straight drink variant 3
        function app = straightDrink3(app)
            
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1), app.shelf_pos_arr(3,2)-0.3, app.shelf_pos_arr(3,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 1 1 1], 1);
            app.holdbottle_(1,:) = [true 3];
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.1,-0.07,0.17) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(pi/2), 1);

            app = app.moveToPoseIkcon(app.getPoseT(1) * trotz(-pi/2), 1);
            app = app.moveToPoseRMRC(transl(app.mixer_pos) * transl(0.23,0.5,0.3) * trotx(pi/2), [1 1 1 0 0 0], 1);
            app = app.moveToPoseIkcon(app.getPoseT(1) * troty(pi/2), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1)+0.5, app.shelf_pos_arr(3,2)-0.5, app.shelf_pos_arr(3,3)-0.2) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseIkcon(transl(app.shelf_pos_arr(3,1), app.shelf_pos_arr(3,2)-0.3, app.shelf_pos_arr(3,3)+0.1) * trotx(-pi/2) * trotz(pi), 1);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, 0.18), [1 1 1 0 0 0], 1);
            app.holdbottle_(1,:) = [false 3];
            app.SnapBottleTo(3, app.shelf_pos_arr(3,:), [0,0,0]);
            app = app.moveToPoseRMRC(app.getPoseT(1) * transl(0, 0, -0.18), [1 1 1 1 1 1], 1);

            % robot 2
            % Move to shaker
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Hold shaker
            app.holdbottle_(2,:) = [true 5];
            % Move to above glass
            app = app.moveToPoseRMRC(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)+0.2), [1 1 1 0 0 0], 2 );
            % Move to the shaker's start position
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % let go of shaker 
            app.holdbottle_(2,:) = [false 5];
            % move to glass start pos
            app = app.moveToPoseRMRC(transl(app.glass_spawn(1,1),app.glass_spawn(1,2),app.glass_spawn(1,3)), [1 1 1 0 0 0], 2 );
            % Pick up glass
            app.holdbottle_(2,:) = [true 4];   % where x is the index of the bottle in app.bottles
            % Move to drop off location (for user pickup)
            app = app.moveToPoseRMRC(transl(app.glass_end_loc(1,1),app.glass_end_loc(1,2),app.glass_end_loc(1,3)), [1 1 1 0 0 0], 2 );
            % let go of bottle
            app.holdbottle_(2,:) = [false 4];   % where x is the index of the bottle in app.bottles
            % Move back to shaker (start spot)
            app = app.moveToPoseRMRC(transl(app.mixer_pos(1,1), app.mixer_pos(1,2), app.mixer_pos(1,3)), [1 1 1 0 0 0], 2 );
            % Move glass and shaker back 
            app.SnapBottleTo(4, app.glass_spawn,[0,0,0]);
            app.SnapBottleTo(5, app.mixer_pos,[0,0,0]);
        end


        %% get pose.T
        function output = getPoseT(app,robotNum)
            if robotNum == 1
                output = app.robot1.model.fkine(app.robot1.model.getpos()).T;
            else
                output = app.robot2.model.fkine(app.robot2.model.getpos()).T;
            end
        end

        %% get Joint State
        function output = getJointState(app, pose, robotNum)
            if robotNum == 1
                output = app.robot1.model.ikcon(pose, app.robot1.model.getpos()); % joint values for target pose
            else
                output = app.robot2.model.fkine(app.robot2.model.getpos()).T;
            end
        end


        %% Move Bottle
        function HoldBottle(app, bottleNum, robotNum)
            % Update the position of a bottle based on the input handle and 
            % end effector pos, i.e. make the relative pose of the bottle 
            % 'sticky' to the pose of the end effector

            t = hgtransform;
            set(app.bottles{bottleNum}, 'Parent', t);
            set(t, 'Matrix', (app.getPoseT(robotNum)) * trotx(-pi/2) * transl(0,-0.15,-0.10));
        end
        
        %% Teleport Bottle
        function SnapBottleTo(app, bottleNum, trans, rpy)
            % Update the position of a bottle based on the input handle and transform + rotation values

            t = hgtransform;
            set(app.bottles{bottleNum}, 'Parent', t);
            set(t, 'Matrix', (transl(trans) * rpy2tr(rpy)));
        end

        %% Draw Grippers
        function app = commandGrippers(app, command)
            
                       
            switch(command)
                case 'update'
                    % Gripper 1
                    t = hgtransform;
                    set(app.gripperLeft1{1}, 'Parent', t);
                    set(t, 'Matrix', (app.getPoseT(1) * transl(-0.03, 0, 0.07) * trotx(-pi/2) * trotz(-pi/2) * trotx(pi) * trotz(app.gripperLeft1{2})));
                    
                    t = hgtransform;
                    set(app.gripperRight1{1}, 'Parent', t);
                    set(t, 'Matrix', (app.getPoseT(1) * transl(0.03, 0, 0.07) * trotx(-pi/2) * trotz(-pi/2) * trotz(app.gripperRight1{2})));
                    
                    t = hgtransform;
                    set(app.gripperBase1, 'Parent', t);
                    set(t, 'Matrix', (app.getPoseT(1) * transl(0, 0, 0) * trotx(-pi/2) * trotz(-pi/2)));
                    
                    % Gripper 2
                    t = hgtransform;
                    set(app.gripperLeft2{1}, 'Parent', t);
                    set(t, 'Matrix', (app.getPoseT(2) * transl(-0.03, 0, 0.07) * trotx(-pi/2) * trotz(-pi/2) * trotx(pi) * trotz(app.gripperLeft1{2})));
                    
                    t = hgtransform;
                    set(app.gripperRight2{1}, 'Parent', t);
                    set(t, 'Matrix', (app.getPoseT(2) * transl(0.03, 0, 0.07) * trotx(-pi/2) * trotz(-pi/2) * trotz(app.gripperRight1{2})));
                    
                    t = hgtransform;
                    set(app.gripperBase2, 'Parent', t);
                    set(t, 'Matrix', (app.getPoseT(2) * transl(0, 0, 0) * trotx(-pi/2) * trotz(-pi/2)));
                    
    
                case 'open'
                    Logger().write('open claw');
                    Logger().write(' ');
                    app.steps = app.steps/2;


                    app.steps = app.steps*2;
    
                case 'close'
                    Logger().write('close claw');
                    Logger().write(' ');
                    app.steps = app.steps/2;


                    app.steps = app.steps*2;
            end
        
        end
        
        %% Update graphics
        function app = frameUpdate(app)
           
            app = app.commandGrippers('update');
            if app.holdbottle_(1,1) == true
                app.HoldBottle(app.holdbottle_(1,2), 1);
            end
            if app.holdbottle_(2,1) == true
                app.HoldBottle(app.holdbottle_(2,2), 2);
            end
            

            if (app.joy_status == true)
                [~, buttons, ~] = read(app.joy); %get controller values
                if (buttons(13) == 1 || app.joyToggle == true) % manually control variable on) % if ps5 button is pressed, loop manual control code until ps5 button is released then pressed again
                    app.joyToggle = true; % manually control variable on
                    button_release = false;
                    
                    while (app.joyToggle == true) 
                        app = app.commandGrippers('update');
                        joystick_command();
                        pause(app.dt);
                        
                        if (buttons(13) == 0)
                            button_release = true;
                        end
                        if (button_release == true && buttons(13) == 1)
                            app.joyToggle = false;
                        end
                    end
                    pause(2);
                end
            end

            drawnow();
            pause(app.dt);
            
        end

        %% Free move to given pose with ikcon
        function app = moveToPoseIkcon(app, pose, robotNum)
            
            robots = {app.robot1, app.robot2};

            q0 = robots{robotNum}.model.getpos(); % Initial joint values
            qf = robots{robotNum}.model.ikcon(pose, q0); % joint values for target pose
           for q = 1:size(qf,2)
                while abs(qf(q)) > 2*pi
                    if qf(q) > 0
                        qf(q) = qf(q) - pi;
                    else
                        qf(q) = qf(q) + pi;
                    end
                end
            end

            qMatrix = jtraj(q0, qf, app.steps);

            Logger().write(['   Target Joint State: ',mat2str(qf)]);
            Logger().write(['   Target Pose: ',mat2str(pose)]);
            
            for frame = 1:app.steps

                if app.isEmergencyStopTriggered == true
                    CurrentPos = app.robot1.model.getpos();
                    app.robot1.model.animate(CurrentPos);
                 
                    CurrentPos2 = app.robot2.model.getpos();
                    app.robot2.model.animate(CurrentPos2);

                 % Wait until the emergency stop is released
                while app.isEmergencyStopTriggered
                    pause(0.1); % Brief pause to prevent busy waiting
                end
                
                disp('EMERGENCY STOP RELEASED. Resuming movement.');

                 else
                    robots{robotNum}.model.animate(qMatrix(frame,:));
    
                    app = app.frameUpdate();
                end
            end
            ActPose = app.getPoseT(robotNum);
            
            if sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2) > 0.05
                
                Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
                Logger().write(['   Δ = ',num2str(sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)),' Failed to achieve desired pose']);

            else
            
                Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
                Logger().write(['   Δ = ',mat2str((sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)))]);
                Logger().write(' ');

            end
        end


        %% Free move to given pose with Resolved Motion Rate Control (RMRC) 
        function app = moveToPoseRMRC(app, pose, mask, robotNum)
            
            robots = {app.robot1, app.robot2};

            q0 = robots{robotNum}.model.getpos();

            T0 = app.getPoseT(robotNum);     % Starting position
            Tf = pose;                       % Ending position

            x0 = transl(T0);                        % Starting position (x, y, z)
            xf = transl(Tf);                        % Target position (x, y, z)
          
            rpy0 = tr2rpy(T0);                      % Starting orientation (roll, pitch, yaw)
            rpyf = tr2rpy(Tf);                      % Target orientation (roll, pitch, yaw)

            % Adjust `xf` based on the mask for position [x y z]
            %xf = x0 .* ~mask(1:3) + xf .* mask(1:3);
            
            % Adjust `rpyf` based on the mask for orientation [roll pitch yaw]
            %rpyf = rpy0 .* ~mask(4:6) + rpyf .* mask(4:6);

            s = lspb(0, 1, app.steps);             % LSPB interpolation scalar

            lambda = 0.2;
            
            % Initialize arrays for storing trajectory
            pos_trajectory = zeros(3, app.steps);     % Position trajectory (x, y, z)
            rpy_trajectory = zeros(3, app.steps);     % Orientation trajectory (roll, pitch, yaw)
            
            % Interpolate both position and orientation
            for i = 1:app.steps
                
                 if app.isEmergencyStopTriggered == true
                    CurrentPos = robots{robotNum}.model.getpos();
                    robots{robotNum}.model.animate(CurrentPos);

                    CurrentPos2 = app.robot2.model.getpos(); 
                    app.robot2.model.animate(CurrentPos2);
                 
                 % Wait until the emergency stop is released
                while app.isEmergencyStopTriggered
                    pause(0.1); % Brief pause to prevent busy waiting
                end
                
                disp('EMERGENCY STOP RELEASED. Resuming movement.');

                 else
                
                % Linear interpolation for position (3D space)
                pos_trajectory(:, i) = x0*(1-s(i)) + s(i)*xf;
                
                % Linear interpolation for orientation (RPY)
                rpy_trajectory(:, i) = rpy0*(1-s(i)) + s(i)*rpyf;
                 end
            end
            
            qMatrix = nan(app.steps,6);
            qMatrix(1,:) = robots{robotNum}.model.ikine(T0, 'q0', q0, 'mask', mask);                 % Solve for joint angles

            for i = 1:app.steps-1

                xdot_pos = (pos_trajectory(:, i+1) - pos_trajectory(:, i)) / app.dt;  % Linear velocity
                xdot_ori = (rpy_trajectory(:, i+1) - rpy_trajectory(:, i)) / app.dt;  % Angular velocity
                xdot = [xdot_pos; xdot_ori];   % Full twist (6x1 vector: [vx; vy; vz; wx; wy; wz])

                J = robots{robotNum}.model.jacob0(qMatrix(i,:));                   % Get the Jacobian at the current state
                Jinv_dls = inv((J'*J)+lambda^2*eye(6))*J';
                qdot = Jinv_dls*xdot;                                              % Solve velocitities via RMRC
                qMatrix(i+1,:) =  qMatrix(i,:) + app.dt*qdot';             % Update next joint state

            end

            for frame = 1:app.steps 
                
                if app.isEmergencyStopTriggered == true
                    CurrentPos = app.robot1.model.getpos();
                    app.robot1.model.animate(CurrentPos);

                    CurrentPos2 = app.robot2.model.getpos();
                    app.robot2.model.animate(CurrentPos2);
                 
                 % Wait until the emergency stop is released
                while app.isEmergencyStopTriggered
                    pause(0.1); % Brief pause to prevent busy waiting
                end
                
                disp('EMERGENCY STOP RELEASED. Resuming movement.');

                 else
                
                robots{robotNum}.model.animate(qMatrix(frame,:));

                app = app.frameUpdate();
                end

            end
            ActPose = app.getPoseT(robotNum);
            
            if sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2) > 0.05
                Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
                Logger().write(['   Δ = ',num2str(sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)),' Failed to achieve desired pose']);
            
            else
            
                Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
                Logger().write(['   Δ = ',mat2str((sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)))]);
                Logger().write(' ');

            end
        end

        
        %% Move to given Joint State
        function app = moveManual(app, qf, robotNum)

            robots = {app.robot1, app.robot2};

            q0 = robots{robotNum}.model.getpos(); % Initial joint values          

            qSuperMatrix = zeros(app.steps, size(qf,2));
            for i = 1:size(qf,2)
                if ~isnan(qf(i))
                    qMatrix = jtraj(q0(i), qf(i), app.steps);
                    qSuperMatrix(:,i) = qMatrix;
                else
                    qSuperMatrix(:,i) = q0(i);
                    qf(i) = q0(i);
                end
            end

            pose = robots{robotNum}.model.fkine(qf).T;
            Logger().write(['   Moving to Joint State ',mat2str(qf)]);
            Logger().write(['   Target Pose ',mat2str(pose)]);
            
            for frame = 1:app.steps 
                
                if app.isEmergencyStopTriggered == true
                    CurrentPos = app.robot1.model.getpos();
                    app.robot1.model.animate(CurrentPos);

                    CurrentPos2 = app.robot2.model.getpos();
                    app.robot2.model.animate(CurrentPos2);
                 
                 % Wait until the emergency stop is released
                while app.isEmergencyStopTriggered
                    pause(0.1); % Brief pause to prevent busy waiting
                end
                
                disp('EMERGENCY STOP RELEASED. Resuming movement.');

                else

                robots{robotNum}.model.animate(qSuperMatrix(frame,:));

                app = app.frameUpdate();
                end
            end
            ActPose = app.getPoseT(robotNum);
            
            if sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2) > 0.05
                Logger().write(['   Δ = ',sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2),' Failed to achieve desired pose']);

            else

            Logger().write(['   Actual Pose: ',mat2str(ActPose)]);
            Logger().write(['   Δ = ',mat2str((sqrt((ActPose(1,4)-pose(1,4))^2+(ActPose(2,4)-pose(2,4))^2+(ActPose(3,4)-pose(3,4))^2)))]);
            Logger().write(' ');

            end
        end

%% Setter function
        function app = EmergencyStopTriggeredTrue(app)

            app.isEmergencyStopTriggered = true;
            disp('Emergency stop triggered ');

        end

%% Setter function
        function app = EmergencyStopTriggeredFalse(app)

            app.isEmergencyStopTriggered = false;
            disp('Emergency stop reset ');

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PourWhiskyButton
        function PourWhiskyButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.PourWhiskyButton.Enable = 'off';
            app.PourCognacButton.Enable = 'off';
            app.PourVodkaButton.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';

            % Run function code for drink 1
            app = app.straightDrink1();

            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.PourWhiskyButton.Enable = 'on';
                app.PourVodkaButton.Enable = 'on';
                app.PourCognacButton.Enable = 'on';
                app.ManualControlButton.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end

        % Button pushed function: PourVodkaButton
        function PourVodkaButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.PourWhiskyButton.Enable = 'off';
            app.PourVodkaButton.Enable = 'off';
            app.PourCognacButton.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';

            % Run function code for drink 2
            app = app.straightDrink2();
    
            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.PourWhiskyButton.Enable = 'on';
                app.PourVodkaButton.Enable = 'on';
                app.PourCognacButton.Enable = 'on';
                app.ManualControlButton.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end

        % Button pushed function: LoadEnvironmentButton
        function LoadEnvironmentButtonPushed(app, event)
            % Turn off load environment button
            % only pressed once at the start
            app.PourMix1Button.Enable = 'off';
            app.PourMix2Button.Enable = 'off';
            app.PourWhiskyButton.Enable = 'off';
            app.PourVodkaButton.Enable = 'off';
            app.PourCognacButton.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.LoadEnvironmentButton.Enable = 'off';
            
            % Start the timer that checks if arduino estop is pressed
            if strcmp(app.estopTimer.Running, 'on')
                stop(app.estopTimer);
            end
            start(app.estopTimer);
            
            % Load environment
            app = app.initialiseScene();
            
            % Set up arduino 
            % clear app.a;
            % app.a = arduino();
            
            % Re-enable buttons after function is complete
            app.PourMix1Button.Enable = 'on';
            app.PourMix2Button.Enable = 'on';
            app.PourWhiskyButton.Enable = 'on';
            app.PourVodkaButton.Enable = 'on';
            app.PourCognacButton.Enable = 'on';
            app.ManualControlButton.Enable = 'on';
            app.EmergencyStopButton.UserData = false;
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.ResumeButton.Enable = 'off';
            app = app.EmergencyStopTriggeredFalse();
             
            app.EmergencyStopButton.UserData = false;
            app.buttonPressed = false;
            app.ResumeButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.EmergencyStopButton.UserData = true;
            app.joyToggle = false;
            app.PourWhiskyButton.Enable = 'off';
            app.PourVodkaButton.Enable = 'off';
            app.PourCognacButton.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app = app.EmergencyStopTriggeredTrue();
            
            %       app.self.robot1.model.animate(CurrentPos);
            disp('ESTOP PRESSED2');
            disp(app.isEmergencyStopTriggered);

            app.ResumeButton.Enable = 'on';
            app.EmergencyStopButton.Enable = 'off';
        end

        % Button pushed function: ManualControlButton
        function ManualControlButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.PourWhiskyButton.Enable = 'off';
            app.PourVodkaButton.Enable = 'off';
            app.PourCognacButton.Enable = 'off';
            app.ManualControlButton.Enable = 'on';
            app.EmergencyStopButton.Enable = 'on';
          
            % Toggle joystick on/off
            app.joyToggle = ~app.joyToggle;
            app = app.frameUpdate();
            
    
            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.PourWhiskyButton.Enable = 'on';
                app.PourCognacButton.Enable = 'on';
                app.PourVodkaButton.Enable = 'on';
                app.ManualControlButton.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end

        % Button pushed function: PourCognacButton
        function PourCognacButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.PourWhiskyButton.Enable = 'off';
            app.PourVodkaButton.Enable = 'off';
            app.PourCognacButton.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';

            % Run function code for drink 2
            app = app.straightDrink3();
    
            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.PourWhiskyButton.Enable = 'on';
                app.PourCognacButton.Enable = 'on';
                app.PourVodkaButton.Enable = 'on';
                app.ManualControlButton.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            app.suppressGraphics = ~app.suppressGraphics;
            
        end

        % Button pushed function: PourMix1Button
        function PourMix1ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.PourWhiskyButton.Enable = 'off';
            app.PourMix1Button.Enable = 'off';
            app.PourMix2Button.Enable = 'off';
            app.PourVodkaButton.Enable = 'off';
            app.PourCognacButton.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';

            % Run function code for drink 2
            app = app.mixerOption1();
    
            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.PourMix1Button.Enable = 'on';
                app.PourMix2Button.Enable = 'on';
                app.PourWhiskyButton.Enable = 'on';
                app.PourCognacButton.Enable = 'on';
                app.PourVodkaButton.Enable = 'on';
                app.ManualControlButton.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end

        % Button pushed function: PourMix2Button
        function PourMix2ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.PourWhiskyButton.Enable = 'off';
            app.PourMix1Button.Enable = 'off';
            app.PourMix2Button.Enable = 'off';
            app.PourVodkaButton.Enable = 'off';
            app.PourCognacButton.Enable = 'off';
            app.ManualControlButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';

            % Run function code for drink 2
            app = app.mixerOption2();
    
            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.PourMix1Button.Enable = 'on';
                app.PourMix2Button.Enable = 'on';
                app.PourWhiskyButton.Enable = 'on';
                app.PourCognacButton.Enable = 'on';
                app.PourVodkaButton.Enable = 'on';
                app.ManualControlButton.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PourVodkaButton = uibutton(app.UIFigure, 'push');
            app.PourVodkaButton.ButtonPushedFcn = createCallbackFcn(app, @PourVodkaButtonPushed, true);
            app.PourVodkaButton.Enable = 'off';
            app.PourVodkaButton.Text = 'Pour Vodka';
            app.PourWhiskyButton = uibutton(app.UIFigure, 'push');
            app.PourWhiskyButton.ButtonPushedFcn = createCallbackFcn(app, @PourWhiskyButtonPushed, true);
            app.PourWhiskyButton.Enable = 'off';
            app.PourWhiskyButton.Text = 'Pour Whisky';
            app.LoadEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.LoadEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEnvironmentButtonPushed, true);
            app.LoadEnvironmentButton.Text = 'Load Environment';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Enable = 'off';
            app.ResumeButton.Text = 'Resume';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Enable = 'off';
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.BarSimulationLabel = uilabel(app.UIFigure);
            app.BarSimulationLabel.Text = 'Bar Simulation';
            app.ChooseADrinkToMakeLabel = uilabel(app.UIFigure);
            app.ChooseADrinkToMakeLabel.Text = 'Choose A Drink To Make';
            app.StartSimulationLabel = uilabel(app.UIFigure);
            app.StartSimulationLabel.Text = 'Start Simulation';
            app.ManualControlButton = uibutton(app.UIFigure, 'push');
            app.ManualControlButton.ButtonPushedFcn = createCallbackFcn(app, @ManualControlButtonPushed, true);
            app.ManualControlButton.Enable = 'off';
            app.ManualControlButton.Text = 'Manual Control';
            app.ControlRobotWithControllerLabel = uilabel(app.UIFigure);
            app.ControlRobotWithControllerLabel.Text = 'Control Robot With Controller';
            app.PourCognacButton = uibutton(app.UIFigure, 'push');
            app.PourCognacButton.ButtonPushedFcn = createCallbackFcn(app, @PourCognacButtonPushed, true);
            app.PourCognacButton.Enable = 'off';
            app.PourCognacButton.Text = 'Pour Cognac';
            app.PourMix1Button = uibutton(app.UIFigure, 'push');
            app.PourMix1Button.ButtonPushedFcn = createCallbackFcn(app, @PourMix1ButtonPushed, true);
            app.PourMix1Button.Enable = 'off';
            app.PourMix1Button.Text = 'Pour Mix 1';
            app.PourMix2Button = uibutton(app.UIFigure, 'push');
            app.PourMix2Button.ButtonPushedFcn = createCallbackFcn(app, @PourMix2ButtonPushed, true);
            app.PourMix2Button.Enable = 'off';
            app.PourMix2Button.Text = 'Pour Mix 2';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Toggle suppress graphics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUItest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end